classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ECE575ProjectUIFigure    matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        GridLayout2              matlab.ui.container.GridLayout
        ECE575CellSynchronizationProjectLabel  matlab.ui.control.Label
        Image                    matlab.ui.control.Image
        TabGroup                 matlab.ui.container.TabGroup
        LTETab                   matlab.ui.container.Tab
        GridLayout3              matlab.ui.container.GridLayout
        Label                    matlab.ui.control.Label
        CellIDLabel              matlab.ui.control.Label
        DecodedLamp              matlab.ui.control.Lamp
        DecodedLampLabel         matlab.ui.control.Label
        DecodingLamp             matlab.ui.control.Lamp
        DecodingLampLabel        matlab.ui.control.Label
        ReceivingLamp            matlab.ui.control.Lamp
        ReceivingLampLabel       matlab.ui.control.Label
        TransmittingLamp         matlab.ui.control.Lamp
        TransmittingLampLabel    matlab.ui.control.Label
        ResultsLabel             matlab.ui.control.Label
        DelayEditField           matlab.ui.control.NumericEditField
        DelayEditFieldLabel      matlab.ui.control.Label
        SimulationLabel          matlab.ui.control.Label
        RunningLamp              matlab.ui.control.Lamp
        RunningLampLabel         matlab.ui.control.Label
        GapSpinner               matlab.ui.control.Spinner
        GapSpinnerLabel          matlab.ui.control.Label
        VRBLengthSpinner         matlab.ui.control.Spinner
        VRBLengthSpinnerLabel    matlab.ui.control.Label
        VRBSTARTSpinner          matlab.ui.control.Spinner
        VRBSTARTSpinnerLabel     matlab.ui.control.Label
        DCIFormatDropDown        matlab.ui.control.DropDown
        DCIFormatDropDownLabel   matlab.ui.control.Label
        SNREditField             matlab.ui.control.NumericEditField
        SNREditFieldLabel        matlab.ui.control.Label
        cellRefPorts             matlab.ui.control.Label
        CellRefPortsLabel        matlab.ui.control.Label
        numOfFrame               matlab.ui.control.Label
        NumberofFrameLabel       matlab.ui.control.Label
        totSubframes             matlab.ui.control.Label
        TotalSubframesLabel      matlab.ui.control.Label
        cfi                      matlab.ui.control.Label
        CFILabel                 matlab.ui.control.Label
        RUNButton_2              matlab.ui.control.Button
        RESETButton              matlab.ui.control.Button
        phichDuration            matlab.ui.control.Label
        PHICHDurationLabel       matlab.ui.control.Label
        codingRate               matlab.ui.control.Label
        CodingRateLabel          matlab.ui.control.Label
        numOfAntenna             matlab.ui.control.Label
        NumofAntennaLabel        matlab.ui.control.Label
        duplexMode               matlab.ui.control.Label
        DuplexModeLabel          matlab.ui.control.Label
        modulation               matlab.ui.control.Label
        ModulationLabel          matlab.ui.control.Label
        rb                       matlab.ui.control.Label
        ResoruceBlocksLabel      matlab.ui.control.Label
        ParametersLabel          matlab.ui.control.Label
        CycPrefixDropDown        matlab.ui.control.DropDown
        CycPrefixLabel           matlab.ui.control.Label
        SubframesEditField       matlab.ui.control.NumericEditField
        SubframesEditFieldLabel  matlab.ui.control.Label
        SIBLabel                 matlab.ui.control.Label
        SIBEnableCheckBox        matlab.ui.control.CheckBox
        RNTIEditField            matlab.ui.control.NumericEditField
        RNTIEditFieldLabel       matlab.ui.control.Label
        CELLIDEditField          matlab.ui.control.NumericEditField
        CELLIDEditFieldLabel     matlab.ui.control.Label
        LTERefModeDropDown       matlab.ui.control.DropDown
        LTERefModeDropDownLabel  matlab.ui.control.Label
        ChannelModelButtonGroup  matlab.ui.container.ButtonGroup
        AWGNButton               matlab.ui.control.RadioButton
        RayleighButton           matlab.ui.control.RadioButton
        UIAxes                   matlab.ui.control.UIAxes
        UIAxes_2                 matlab.ui.control.UIAxes
        GTab                     matlab.ui.container.Tab
    end

    
    properties (Access = private)
        rmc
    end
    
    methods (Access = private)
        
        function initLte(app)
            app.rb.Text = num2str(app.rmc.NDLRB);
            app.modulation.Text = app.rmc.PDSCH.Modulation{1};
            app.duplexMode.Text = app.rmc.DuplexMode;
            app.numOfAntenna.Text = num2str(app.rmc.PDSCH.NLayers);
            app.codingRate.Text = num2str(app.rmc.PDSCH.TargetCodeRate);
            app.phichDuration.Text = num2str(app.rmc.PHICHDuration);
            app.cfi.Text = num2str(app.rmc.CFI);
            app.totSubframes.Text = num2str(app.rmc.TotSubframes);
            app.numOfFrame.Text = num2str(app.rmc.NFrame);
            app.cellRefPorts.Text = num2str(app.rmc.CellRefP);
            app.CycPrefixDropDown.Value = app.rmc.CyclicPrefix;
            app.rmc.NCellID = app.CELLIDEditField.Value;
            app.rmc.TotSubframes = app.SubframesEditField.Value; % Resource 
            app.rmc.PDSCH.RNTI = app.RNTIEditField.Value;
            % SIB parameters
            if (app.SIBEnableCheckBox.Value)
                app.rmc.SIB.Enable = 'On';
            else
                app.rmc.SIB.Enable = 'Off';
            end
            
            app.rmc.SIB.DCIFormat = app.DCIFormatDropDown.Value;
            app.rmc.SIB.AllocationType = 0;
            app.rmc.SIB.VRBStart = app.VRBSTARTSpinner.Value;
            app.rmc.SIB.VRBLength = app.VRBLengthSpinner.Value;
            app.rmc.SIB.Gap = app.GapSpinner.Value;
            % SIB data field filled with random bits, this is not a valid SIB
            % message
            app.rmc.SIB.Data = randi([0 1],176,1);
        end
        
        function results = initNR(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('./LTE');
            addpath('./5G');
            addpath('./assets');
            
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            if (selectedTab) == "LTE"
                app.rmc = lteRMCDL('R.3');
                initLte(app);
            else

            end

        end

        % Value changed function: LTERefModeDropDown
        function LTERefModeDropDownValueChanged(app, event)
            app.rmc = lteRMCDL(app.LTERefModeDropDown.Value);
            initLte(app);
        end

        % Button pushed function: RUNButton_2
        function RUNButton_2Pushed(app, event)
            app.RunningLamp.Color = "0.00,1.00,0.00";
            lteCellSearchSync(6, app.rmc, app.ChannelModelButtonGroup.SelectedObject.Text, app.SNREditField.Value, true, app.TransmittingLamp, app.ReceivingLamp, app.DecodingLamp);
            app.RunningLamp.Color = "1.00,0.00,0.00";
        end

        % Selection changed function: ChannelModelButtonGroup
        function ChannelModelButtonGroupSelectionChanged(app, event)
            selectedButton = app.ChannelModelButtonGroup.SelectedObject;
            
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            app.rmc = lteRMCDL('R.3');
            initLte(app);
            app.VRBSTARTSpinner.Value = 8;
            app.VRBLengthSpinner.Value = 8;
            app.GapSpinner.Value = 0;
            app.CELLIDEditField.Value = 17;
            app.RNTIEditField.Value = 61;
            app.SubframesEditField.Value = 41;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ECE575ProjectUIFigure = uifigure('Visible', 'off');
            app.ECE575ProjectUIFigure.Name = 'ECE 575 Project';
            app.GridLayout = uigridlayout(app.ECE575ProjectUIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.LTETab = uitab(app.TabGroup);
            app.LTETab.Title = 'LTE';
            app.GridLayout3 = uigridlayout(app.LTETab);
            app.UIAxes_2 = uiaxes(app.GridLayout3);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes = uiaxes(app.GridLayout3);
            title(app.UIAxes, 'Received Signal Spectrum')
            xlabel(app.UIAxes, 'Frequency MHz')
            ylabel(app.UIAxes, 'dBm')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.ChannelModelButtonGroup = uibuttongroup(app.GridLayout3);
            app.ChannelModelButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChannelModelButtonGroupSelectionChanged, true);
            app.ChannelModelButtonGroup.Title = 'Channel Model';
            app.RayleighButton = uiradiobutton(app.ChannelModelButtonGroup);
            app.RayleighButton.Text = 'Rayleigh';
            app.RayleighButton.Value = true;
            app.AWGNButton = uiradiobutton(app.ChannelModelButtonGroup);
            app.AWGNButton.Text = 'AWGN';
            app.LTERefModeDropDownLabel = uilabel(app.GridLayout3);
            app.LTERefModeDropDownLabel.Text = {'LTE '; 'Ref. Mode'};
            app.LTERefModeDropDown = uidropdown(app.GridLayout3);
            app.LTERefModeDropDown.Items = {'R.0', 'R.1', 'R.2', 'R.3', 'R.4', 'R.5', 'R.6', 'R.7', 'R.8', 'R.9', 'R.10', 'R.11', 'R.12', 'R.13', 'R.14', 'R.25', 'R.26', 'R.27', 'R.28', 'R.45'};
            app.LTERefModeDropDown.ValueChangedFcn = createCallbackFcn(app, @LTERefModeDropDownValueChanged, true);
            app.LTERefModeDropDown.Value = 'R.0';
            app.CELLIDEditFieldLabel = uilabel(app.GridLayout3);
            app.CELLIDEditFieldLabel.Text = 'CELL ID';
            app.CELLIDEditField = uieditfield(app.GridLayout3, 'numeric');
            app.CELLIDEditField.Value = 17;
            app.RNTIEditFieldLabel = uilabel(app.GridLayout3);
            app.RNTIEditFieldLabel.Text = 'RNTI';
            app.RNTIEditField = uieditfield(app.GridLayout3, 'numeric');
            app.RNTIEditField.Value = 61;
            app.SIBEnableCheckBox = uicheckbox(app.GridLayout3);
            app.SIBEnableCheckBox.Text = 'SIB Enable';
            app.SIBEnableCheckBox.Value = true;
            app.SIBLabel = uilabel(app.GridLayout3);
            app.SIBLabel.Text = 'SIB';
            app.SubframesEditFieldLabel = uilabel(app.GridLayout3);
            app.SubframesEditFieldLabel.Text = 'Subframes';
            app.SubframesEditField = uieditfield(app.GridLayout3, 'numeric');
            app.SubframesEditField.Value = 41;
            app.CycPrefixLabel = uilabel(app.GridLayout3);
            app.CycPrefixLabel.Text = {'Cyc Prefix'; ''};
            app.CycPrefixDropDown = uidropdown(app.GridLayout3);
            app.CycPrefixDropDown.Items = {'Normal', 'Extended'};
            app.CycPrefixDropDown.Value = 'Normal';
            app.ParametersLabel = uilabel(app.GridLayout3);
            app.ParametersLabel.Text = 'Parameters';
            app.ResoruceBlocksLabel = uilabel(app.GridLayout3);
            app.ResoruceBlocksLabel.Text = 'Resoruce Blocks';
            app.rb = uilabel(app.GridLayout3);
            app.rb.Text = '6';
            app.ModulationLabel = uilabel(app.GridLayout3);
            app.ModulationLabel.Text = 'Modulation';
            app.modulation = uilabel(app.GridLayout3);
            app.modulation.Text = '16-QAM';
            app.DuplexModeLabel = uilabel(app.GridLayout3);
            app.DuplexModeLabel.Text = 'Duplex Mode';
            app.duplexMode = uilabel(app.GridLayout3);
            app.duplexMode.Text = 'FDD';
            app.NumofAntennaLabel = uilabel(app.GridLayout3);
            app.NumofAntennaLabel.Text = 'Num. of Antenna';
            app.numOfAntenna = uilabel(app.GridLayout3);
            app.numOfAntenna.Text = '1';
            app.CodingRateLabel = uilabel(app.GridLayout3);
            app.CodingRateLabel.Text = 'Coding Rate';
            app.codingRate = uilabel(app.GridLayout3);
            app.codingRate.Text = '1/2';
            app.PHICHDurationLabel = uilabel(app.GridLayout3);
            app.PHICHDurationLabel.Text = 'PHICH Duration';
            app.phichDuration = uilabel(app.GridLayout3);
            app.phichDuration.Text = 'Normal';
            app.RESETButton = uibutton(app.GridLayout3, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.RUNButton_2 = uibutton(app.GridLayout3, 'push');
            app.RUNButton_2.ButtonPushedFcn = createCallbackFcn(app, @RUNButton_2Pushed, true);
            app.RUNButton_2.Text = 'RUN';
            app.CFILabel = uilabel(app.GridLayout3);
            app.CFILabel.Text = 'CFI';
            app.cfi = uilabel(app.GridLayout3);
            app.cfi.Text = '1';
            app.TotalSubframesLabel = uilabel(app.GridLayout3);
            app.TotalSubframesLabel.Text = 'Total Subframes';
            app.totSubframes = uilabel(app.GridLayout3);
            app.totSubframes.Text = '0';
            app.NumberofFrameLabel = uilabel(app.GridLayout3);
            app.NumberofFrameLabel.Text = 'Number of Frame';
            app.numOfFrame = uilabel(app.GridLayout3);
            app.numOfFrame.Text = '0';
            app.CellRefPortsLabel = uilabel(app.GridLayout3);
            app.CellRefPortsLabel.Text = 'Cell Ref Ports';
            app.cellRefPorts = uilabel(app.GridLayout3);
            app.cellRefPorts.Text = '1';
            app.SNREditFieldLabel = uilabel(app.GridLayout3);
            app.SNREditFieldLabel.Text = 'SNR';
            app.SNREditField = uieditfield(app.GridLayout3, 'numeric');
            app.SNREditField.Value = 30;
            app.DCIFormatDropDownLabel = uilabel(app.GridLayout3);
            app.DCIFormatDropDownLabel.Text = 'DCI Format';
            app.DCIFormatDropDown = uidropdown(app.GridLayout3);
            app.DCIFormatDropDown.Items = {'Format1A', 'Extended'};
            app.DCIFormatDropDown.Value = 'Format1A';
            app.VRBSTARTSpinnerLabel = uilabel(app.GridLayout3);
            app.VRBSTARTSpinnerLabel.Text = 'VRBSTART';
            app.VRBSTARTSpinner = uispinner(app.GridLayout3);
            app.VRBSTARTSpinner.Value = 8;
            app.VRBLengthSpinnerLabel = uilabel(app.GridLayout3);
            app.VRBLengthSpinnerLabel.Text = 'VRBLength';
            app.VRBLengthSpinner = uispinner(app.GridLayout3);
            app.VRBLengthSpinner.Value = 8;
            app.GapSpinnerLabel = uilabel(app.GridLayout3);
            app.GapSpinnerLabel.Text = 'Gap';
            app.GapSpinner = uispinner(app.GridLayout3);
            app.RunningLampLabel = uilabel(app.GridLayout3);
            app.RunningLampLabel.Text = 'Running';
            app.RunningLamp = uilamp(app.GridLayout3);
            app.SimulationLabel = uilabel(app.GridLayout3);
            app.SimulationLabel.Text = 'Simulation';
            app.DelayEditFieldLabel = uilabel(app.GridLayout3);
            app.DelayEditFieldLabel.Enable = 'off';
            app.DelayEditFieldLabel.Text = 'Delay';
            app.DelayEditField = uieditfield(app.GridLayout3, 'numeric');
            app.DelayEditField.Editable = 'off';
            app.DelayEditField.Enable = 'off';
            app.ResultsLabel = uilabel(app.GridLayout3);
            app.ResultsLabel.Text = 'Results';
            app.TransmittingLampLabel = uilabel(app.GridLayout3);
            app.TransmittingLampLabel.Text = 'Transmitting';
            app.TransmittingLamp = uilamp(app.GridLayout3);
            app.ReceivingLampLabel = uilabel(app.GridLayout3);
            app.ReceivingLampLabel.Text = 'Receiving';
            app.ReceivingLamp = uilamp(app.GridLayout3);
            app.DecodingLampLabel = uilabel(app.GridLayout3);
            app.DecodingLampLabel.Text = 'Decoding';
            app.DecodingLamp = uilamp(app.GridLayout3);
            app.DecodedLampLabel = uilabel(app.GridLayout3);
            app.DecodedLampLabel.Text = 'Decoded';
            app.DecodedLamp = uilamp(app.GridLayout3);
            app.CellIDLabel = uilabel(app.GridLayout3);
            app.CellIDLabel.Text = 'Cell ID';
            app.Label = uilabel(app.GridLayout3);
            app.Label.Text = '0';
            app.GTab = uitab(app.TabGroup);
            app.GTab.Title = '5G';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.Image = uiimage(app.GridLayout2);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'assets', 'ncstate-type-4x1-red.png');
            app.ECE575CellSynchronizationProjectLabel = uilabel(app.GridLayout2);
            app.ECE575CellSynchronizationProjectLabel.Text = 'ECE 575 - Cell Synchronization Project';
            % Show the figure after all components are created
            app.ECE575ProjectUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ECE575ProjectUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ECE575ProjectUIFigure)
        end
    end
end